classdef Optimal_Climate_Pathway < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AboutPanel                    matlab.ui.container.Panel
        Hyperlink_4                   matlab.ui.control.Hyperlink
        DefaultparametersareDICE2016RLabel  matlab.ui.control.Label
        Hyperlink_3                   matlab.ui.control.Hyperlink
        Hyperlink_2                   matlab.ui.control.Hyperlink
        Hyperlink                     matlab.ui.control.Hyperlink
        ForlastversionclickLabel      matlab.ui.control.Label
        AuthorsLabel                  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        EmissionsTab                  matlab.ui.container.Tab
        EmissionsPlot                 matlab.ui.control.UIAxes
        CarbonTab                     matlab.ui.container.Tab
        CarbonPlot                    matlab.ui.control.UIAxes
        AbatementTab                  matlab.ui.container.Tab
        AbatementPlot                 matlab.ui.control.UIAxes
        CarbontaxTab                  matlab.ui.container.Tab
        CarbonTaxPlot                 matlab.ui.control.UIAxes
        TemperatureTab                matlab.ui.container.Tab
        TemperturePlot                matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        ResetparametersButton         matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        DamagecoefficientontemperatureEditField  matlab.ui.control.NumericEditField
        DamagecoefficientontemperatureEditFieldLabel  matlab.ui.control.Label
        FirstdatecanbeginnegativeemissionsEditField  matlab.ui.control.NumericEditField
        FirstdatecanbeginnegativeemissionsEditFieldLabel  matlab.ui.control.Label
        ClimatesensitivitydegCEditField  matlab.ui.control.NumericEditField
        ClimatesensitivitydegCEditFieldLabel  matlab.ui.control.Label
        AversiontoinequalityovertimeEditField  matlab.ui.control.NumericEditField
        AversiontoinequalityovertimeEditFieldLabel  matlab.ui.control.Label
        Initialcostofbackstoptechnology2010pertonCO2EditField  matlab.ui.control.NumericEditField
        Initialcostofbackstoptechnology2010pertonCO2EditFieldLabel  matlab.ui.control.Label
        InitialrateofeconomicgrowthperyearEditField  matlab.ui.control.NumericEditField
        InitialrateofeconomicgrowthperyearEditFieldLabel  matlab.ui.control.Label
        DiscountRateperyearEditField  matlab.ui.control.NumericEditField
        DiscountRateperyearEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        DiscRate =0.015*100 
        InitialRateGrowth = 7.6
        ClimateSensitivity = 3.1
        AversionInequality = 1.45
        CostZeroCarbonTech = 2016.7*44/12
        DateNegativeEmissions = 2165
        DamageCoefficient = 0.00236
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            %[yearVec,Emi,Aba,Tax,Temp] = RunModel(app.DiscRate,app.InitialRateGrowth,app.ClimateSensitivity,app.AversionInequality, app.CostZeroCarbonTech, app.DateNegativeEmissions, app.DamageCoefficient);
            
            running_app = 1;
            run main_dice2016r.m
            
            plot(app.TemperturePlot,yearVec,T,'b');
            plot(app.EmissionsPlot,yearVec,emsind_annualCO2,'b');
            plot(app.AbatementPlot,yearVec,abaterate,'b');
            plot(app.CarbonTaxPlot,yearVec,emtax_pertCO2,'b');
            plot(app.CarbonPlot,yearVec,Carbon_ppm,'b');
        end

        % Value changed function: DiscountRateperyearEditField
        function DiscountRateperyearEditFieldValueChanged(app, event)
            value = app.DiscountRateperyearEditField.Value;
            app.DiscRate = value;
        end

        % Value changed function: 
        % InitialrateofeconomicgrowthperyearEditField
        function InitialrateofeconomicgrowthperyearEditFieldValueChanged(app, event)
            value = app.InitialrateofeconomicgrowthperyearEditField.Value;
            app.InitialRateGrowth = value; 
        end

        % Value changed function: ClimatesensitivitydegCEditField
        function ClimatesensitivitydegCEditFieldValueChanged(app, event)
            value = app.ClimatesensitivitydegCEditField.Value;
            app.ClimateSensitivity = value; 
        end

        % Value changed function: AversiontoinequalityovertimeEditField
        function AversiontoinequalityovertimeEditFieldValueChanged(app, event)
            value = app.AversiontoinequalityovertimeEditField.Value;
            app.AversionInequality = value;
        end

        % Value changed function: 
        % Initialcostofbackstoptechnology2010pertonCO2EditField
        function Initialcostofbackstoptechnology2010pertonCO2EditFieldValueChanged(app, event)
            value = app.Initialcostofbackstoptechnology2010pertonCO2EditField.Value;
            app.CostZeroCarbonTech = value;
        end

        % Value changed function: 
        % FirstdatecanbeginnegativeemissionsEditField
        function FirstdatecanbeginnegativeemissionsEditFieldValueChanged(app, event)
            value = app.FirstdatecanbeginnegativeemissionsEditField.Value;
            app.DateNegativeEmissions = value; 
        end

        % Value changed function: DamagecoefficientontemperatureEditField
        function DamagecoefficientontemperatureEditFieldValueChanged(app, event)
            value = app.DamagecoefficientontemperatureEditField.Value;
            app.DamageCoefficient = value;
        end

        % Button pushed function: ResetparametersButton
        function ResetparametersButtonPushed(app, event)
            app.DiscRate =0.015*100;
            app.InitialRateGrowth = 7.6;
            app.ClimateSensitivity = 3.1;
            app.AversionInequality = 1.45;
            app.CostZeroCarbonTech = 2016.7*44/12;
            app.DateNegativeEmissions = 2165;
            app.DamageCoefficient = 0.00236;
            
            app.DiscountRateperyearEditField.Value = app.DiscRate;
            app.InitialrateofeconomicgrowthperyearEditField.Value = app.InitialRateGrowth;
            app.ClimatesensitivitydegCEditField.Value = app.ClimateSensitivity;
            app.AversiontoinequalityovertimeEditField.Value = app.AversionInequality;
            app.Initialcostofbackstoptechnology2010pertonCO2EditField.Value = app.CostZeroCarbonTech;
            app.FirstdatecanbeginnegativeemissionsEditField.Value = app.DateNegativeEmissions;
            app.DamagecoefficientontemperatureEditField.Value = app.DamageCoefficient;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DiscountRateperyearEditFieldLabel = uilabel(app.Panel);
            app.DiscountRateperyearEditFieldLabel.Text = 'Discount Rate (% per year)';
            app.DiscountRateperyearEditField = uieditfield(app.Panel, 'numeric');
            app.DiscountRateperyearEditField.ValueChangedFcn = createCallbackFcn(app, @DiscountRateperyearEditFieldValueChanged, true);
            app.DiscountRateperyearEditField.Value = 1.5;
            app.InitialrateofeconomicgrowthperyearEditFieldLabel = uilabel(app.Panel);
            app.InitialrateofeconomicgrowthperyearEditFieldLabel.Text = 'Initial rate of economic growth (% per year)';
            app.InitialrateofeconomicgrowthperyearEditField = uieditfield(app.Panel, 'numeric');
            app.InitialrateofeconomicgrowthperyearEditField.ValueChangedFcn = createCallbackFcn(app, @InitialrateofeconomicgrowthperyearEditFieldValueChanged, true);
            app.InitialrateofeconomicgrowthperyearEditField.Value = 7.6;
            app.Initialcostofbackstoptechnology2010pertonCO2EditFieldLabel = uilabel(app.Panel);
            app.Initialcostofbackstoptechnology2010pertonCO2EditFieldLabel.Text = {'Initial cost of "backstop" technology,'; 'such as unlimited air capture'; '(2010$ per ton CO2)'};
            app.Initialcostofbackstoptechnology2010pertonCO2EditField = uieditfield(app.Panel, 'numeric');
            app.Initialcostofbackstoptechnology2010pertonCO2EditField.ValueChangedFcn = createCallbackFcn(app, @Initialcostofbackstoptechnology2010pertonCO2EditFieldValueChanged, true);
            app.Initialcostofbackstoptechnology2010pertonCO2EditField.Value = 7394.56666666667;
            app.AversiontoinequalityovertimeEditFieldLabel = uilabel(app.Panel);
            app.AversiontoinequalityovertimeEditFieldLabel.Text = 'Aversion to inequality over time';
            app.AversiontoinequalityovertimeEditField = uieditfield(app.Panel, 'numeric');
            app.AversiontoinequalityovertimeEditField.ValueChangedFcn = createCallbackFcn(app, @AversiontoinequalityovertimeEditFieldValueChanged, true);
            app.AversiontoinequalityovertimeEditField.Value = 1.45;
            app.ClimatesensitivitydegCEditFieldLabel = uilabel(app.Panel);
            app.ClimatesensitivitydegCEditFieldLabel.Text = 'Climate sensitivity (deg C)';
            app.ClimatesensitivitydegCEditField = uieditfield(app.Panel, 'numeric');
            app.ClimatesensitivitydegCEditField.ValueChangedFcn = createCallbackFcn(app, @ClimatesensitivitydegCEditFieldValueChanged, true);
            app.ClimatesensitivitydegCEditField.Value = 3.1;
            app.FirstdatecanbeginnegativeemissionsEditFieldLabel = uilabel(app.Panel);
            app.FirstdatecanbeginnegativeemissionsEditFieldLabel.Text = 'First date can begin negative emissions';
            app.FirstdatecanbeginnegativeemissionsEditField = uieditfield(app.Panel, 'numeric');
            app.FirstdatecanbeginnegativeemissionsEditField.ValueChangedFcn = createCallbackFcn(app, @FirstdatecanbeginnegativeemissionsEditFieldValueChanged, true);
            app.FirstdatecanbeginnegativeemissionsEditField.Value = 2165;
            app.DamagecoefficientontemperatureEditFieldLabel = uilabel(app.Panel);
            app.DamagecoefficientontemperatureEditFieldLabel.Text = 'Damage coefficient on temperature';
            app.DamagecoefficientontemperatureEditField = uieditfield(app.Panel, 'numeric');
            app.DamagecoefficientontemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @DamagecoefficientontemperatureEditFieldValueChanged, true);
            app.DamagecoefficientontemperatureEditField.Value = 0.00236;
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetparametersButton = uibutton(app.Panel, 'push');
            app.ResetparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetparametersButtonPushed, true);
            app.ResetparametersButton.Text = 'Reset parameters';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EmissionsTab = uitab(app.TabGroup);
            app.EmissionsTab.Title = 'Emissions';
            app.EmissionsPlot = uiaxes(app.EmissionsTab);
            title(app.EmissionsPlot, 'Industrial Emissions')
            xlabel(app.EmissionsPlot, 'Year')
            ylabel(app.EmissionsPlot, 'GtCO2 per year')
            zlabel(app.EmissionsPlot, 'Z')
            app.CarbonTab = uitab(app.TabGroup);
            app.CarbonTab.Title = 'Carbon';
            app.CarbonPlot = uiaxes(app.CarbonTab);
            title(app.CarbonPlot, 'Atmospheric Carbon')
            xlabel(app.CarbonPlot, 'Year')
            ylabel(app.CarbonPlot, 'ppm')
            zlabel(app.CarbonPlot, 'Z')
            app.AbatementTab = uitab(app.TabGroup);
            app.AbatementTab.Title = 'Abatement';
            app.AbatementPlot = uiaxes(app.AbatementTab);
            title(app.AbatementPlot, 'Abatement')
            xlabel(app.AbatementPlot, 'Year')
            ylabel(app.AbatementPlot, 'Fraction of Emissions abated')
            zlabel(app.AbatementPlot, 'Z')
            app.CarbontaxTab = uitab(app.TabGroup);
            app.CarbontaxTab.Title = 'Carbon tax';
            app.CarbonTaxPlot = uiaxes(app.CarbontaxTab);
            title(app.CarbonTaxPlot, 'Carbon Tax')
            xlabel(app.CarbonTaxPlot, 'Year')
            ylabel(app.CarbonTaxPlot, '2010$ per tCO2')
            zlabel(app.CarbonTaxPlot, 'Z')
            app.TemperatureTab = uitab(app.TabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.TemperturePlot = uiaxes(app.TemperatureTab);
            title(app.TemperturePlot, 'Temperature')
            xlabel(app.TemperturePlot, 'Year')
            ylabel(app.TemperturePlot, 'deg C, relative to 1900')
            zlabel(app.TemperturePlot, 'Z')
            app.AboutPanel = uipanel(app.UIFigure);
            app.AboutPanel.Title = 'About';
            app.AuthorsLabel = uilabel(app.AboutPanel);
            app.AuthorsLabel.Text = 'Authors:';
            app.ForlastversionclickLabel = uilabel(app.AboutPanel);
            app.ForlastversionclickLabel.Text = 'For last version click';
            app.Hyperlink = uihyperlink(app.AboutPanel);
            app.Hyperlink.URL = 'https://www.dereklemoine.com/';
            app.Hyperlink.Text = 'Derek Lemoine';
            app.Hyperlink_2 = uihyperlink(app.AboutPanel);
            app.Hyperlink_2.URL = 'www.ernestoriveramora.com';
            app.Hyperlink_2.Text = 'Ernesto Rivera Mora';
            app.Hyperlink_3 = uihyperlink(app.AboutPanel);
            app.Hyperlink_3.URL = 'https://github.com/dlemoine1/DICE-2016R-Matlab';
            app.Hyperlink_3.Text = 'here';
            app.DefaultparametersareDICE2016RLabel = uilabel(app.AboutPanel);
            app.DefaultparametersareDICE2016RLabel.Text = 'Default parameters are DICE-2016R:';
            app.Hyperlink_4 = uihyperlink(app.AboutPanel);
            app.Hyperlink_4.URL = 'https://www.pnas.org/content/114/7/1518';
            app.Hyperlink_4.Text = 'Nordhaus (2017)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Optimal_Climate_Pathway

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end